classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExitButton                 matlab.ui.control.Button
        IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2  matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        UploadzoneTab              matlab.ui.container.Tab
        MenuLabel                  matlab.ui.control.Label
        GotocalculationzoneButton  matlab.ui.control.Button
        LoadpostinterventionimageButton  matlab.ui.control.Button
        LoadbaselineimageButton    matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        postAxes                   matlab.ui.control.UIAxes
        preAxes                    matlab.ui.control.UIAxes
        CalculationzoneTab         matlab.ui.container.Tab
        res                        matlab.ui.control.Label
        DifferenceestimationLabel  matlab.ui.control.Label
        DifferenceimageLabel       matlab.ui.control.Label
        BaselineandpostinterventionimagesLabel  matlab.ui.control.Label
        CalculateestimatedmicrocirculationButton  matlab.ui.control.Button
        GobacktouploadzoneButton   matlab.ui.control.Button
        MenuLabel_2                matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        calc_diff_axes_1           matlab.ui.control.UIAxes
        calc_post_axes             matlab.ui.control.UIAxes
        calc_base_axes             matlab.ui.control.UIAxes
        Image                      matlab.ui.control.Image
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        baseline_image % Description
        post_image % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadbaselineimageButton
        function LoadbaselineimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.baseline_image = imread(fullname);
           imshow(app.baseline_image, 'Parent' , app.preAxes);

           if((~isempty(app.baseline_image)) && (~isempty(app.post_image)))
               app.GotocalculationzoneButton.Enable = 'on';
           end

        end

        % Button pushed function: LoadpostinterventionimageButton
        function LoadpostinterventionimageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.post_image = imread(fullname);
           imshow(app.post_image, 'Parent' , app.postAxes);

           if((~isempty(app.baseline_image)) && (~isempty(app.post_image)))
               app.GotocalculationzoneButton.Enable = 'on';
           end
        end

        % Button pushed function: GotocalculationzoneButton
        function GotocalculationzoneButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CalculationzoneTab;
        end

        % Button pushed function: CalculateestimatedmicrocirculationButton
        function CalculateestimatedmicrocirculationButtonPushed(app, event)
            Z = imabsdiff(app.baseline_image,app.post_image);
            imshow(app.baseline_image, 'Parent' , app.calc_base_axes);
            imshow(app.post_image, 'Parent' , app.calc_post_axes);
            imshow(Z, 'Parent' , app.calc_diff_axes_1);            
            nonzeros = nnz(Z);
            zeros = nnz(~Z);            
            percentage_in_difference = (nonzeros/(zeros+nonzeros))*100;
%             disp(percentage_in_difference);
            app.res.Text = string(percentage_in_difference);
        end

        % Button pushed function: GobacktouploadzoneButton
        function GobacktouploadzoneButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.UploadzoneTab;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ua_aesthetic.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadzoneTab = uitab(app.TabGroup);
            app.UploadzoneTab.Title = 'Upload zone';
            app.preAxes = uiaxes(app.UploadzoneTab);
            zlabel(app.preAxes, 'Z')
            app.preAxes.XTickLabel = '';
            app.preAxes.YTickLabel = '';
            app.postAxes = uiaxes(app.UploadzoneTab);
            zlabel(app.postAxes, 'Z')
            app.postAxes.XTickLabel = '';
            app.postAxes.YTickLabel = '';
            app.Label_2 = uilabel(app.UploadzoneTab);
            app.Label_2.Text = '';
            app.LoadbaselineimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadbaselineimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbaselineimageButtonPushed, true);
            app.LoadbaselineimageButton.Text = 'Load baseline image';
            app.LoadpostinterventionimageButton = uibutton(app.UploadzoneTab, 'push');
            app.LoadpostinterventionimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpostinterventionimageButtonPushed, true);
            app.LoadpostinterventionimageButton.Text = 'Load post intervention image';
            app.GotocalculationzoneButton = uibutton(app.UploadzoneTab, 'push');
            app.GotocalculationzoneButton.ButtonPushedFcn = createCallbackFcn(app, @GotocalculationzoneButtonPushed, true);
            app.GotocalculationzoneButton.Enable = 'off';
            app.GotocalculationzoneButton.Text = 'Go to calculation zone';
            app.MenuLabel = uilabel(app.UploadzoneTab);
            app.MenuLabel.Text = 'Menu';
            app.CalculationzoneTab = uitab(app.TabGroup);
            app.CalculationzoneTab.Title = 'Calculation zone';
            app.calc_base_axes = uiaxes(app.CalculationzoneTab);
            title(app.calc_base_axes, {''; ''})
            app.calc_base_axes.XTickLabel = '';
            app.calc_base_axes.YTickLabel = '';
            app.calc_post_axes = uiaxes(app.CalculationzoneTab);
            title(app.calc_post_axes, {''; ''})
            app.calc_post_axes.XTickLabel = '';
            app.calc_post_axes.YTickLabel = '';
            app.calc_diff_axes_1 = uiaxes(app.CalculationzoneTab);
            title(app.calc_diff_axes_1, {''; ''})
            app.calc_diff_axes_1.XTickLabel = '';
            app.calc_diff_axes_1.YTickLabel = '';
            app.Label_3 = uilabel(app.CalculationzoneTab);
            app.Label_3.Text = '';
            app.MenuLabel_2 = uilabel(app.CalculationzoneTab);
            app.MenuLabel_2.Text = 'Menu';
            app.GobacktouploadzoneButton = uibutton(app.CalculationzoneTab, 'push');
            app.GobacktouploadzoneButton.ButtonPushedFcn = createCallbackFcn(app, @GobacktouploadzoneButtonPushed, true);
            app.GobacktouploadzoneButton.Text = 'Go back to upload zone';
            app.CalculateestimatedmicrocirculationButton = uibutton(app.CalculationzoneTab, 'push');
            app.CalculateestimatedmicrocirculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateestimatedmicrocirculationButtonPushed, true);
            app.CalculateestimatedmicrocirculationButton.Text = 'Calculate estimated microcirculation';
            app.BaselineandpostinterventionimagesLabel = uilabel(app.CalculationzoneTab);
            app.BaselineandpostinterventionimagesLabel.Text = 'Baseline and post intervention images';
            app.DifferenceimageLabel = uilabel(app.CalculationzoneTab);
            app.DifferenceimageLabel.Text = 'Difference image';
            app.DifferenceestimationLabel = uilabel(app.CalculationzoneTab);
            app.DifferenceestimationLabel.Text = 'Difference estimation (%) : ';
            app.res = uilabel(app.CalculationzoneTab);
            app.res.Text = '';
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2 = uilabel(app.UIFigure);
            app.IntramuscularflowAssessmentwithEsaoteMicroVMeasurementsLabel_2.Text = 'Intramuscular flow Assessment with Esaote MicroV Measurements';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end